classdef ReadVideoSaveImage < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        UIAxes                   matlab.ui.control.UIAxes
        videoNameEditFieldLabel  matlab.ui.control.Label
        videoNameEditField       matlab.ui.control.EditField
        ReadButton               matlab.ui.control.Button
        SaveButton               matlab.ui.control.Button
        FrameSliderLabel         matlab.ui.control.Label
        FrameSlider              matlab.ui.control.Slider
        Lamp                     matlab.ui.control.Lamp
    end


    methods (Access = private)
    
        function frameMax = FrameMax(app, videoName)
            
            video = VideoReader(videoName);
            frameMax = video.NumberOfFrame;
        end
        
        function results = DispFrame(app, frame)
            global videoName Img
            video = VideoReader(videoName);
            Img = read(video, frame);
            imshow(Img,'Parent',app.UIAxes);
            
        end
        
    end


    methods (Access = private)

        % Button pushed function: ReadButton
        function ReadButtonPushed(app, event)
            global videoName;
            videoName = app.videoNameEditField.Value;
            try
                frameMax = FrameMax(app, videoName);
                app.FrameSlider.Limits = [0,frameMax];
                app.Lamp.Color = 'green';
            catch
                app.videoNameEditField.Value = 'Name Error';
                app.Lamp.Color = 'red';
            end
        end

        % Value changed function: FrameSlider
        function FrameSliderValueChanged(app, event)
            global frame
            frame = round(app.FrameSlider.Value);
            app.FrameSliderLabel.Text = num2str(frame);
            DispFrame(app, frame);
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            global Img frame
            ImgName = strcat('Save',num2str(frame),'.png');
            imwrite(Img, ImgName);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.videoNameEditFieldLabel = uilabel(app.UIFigure);
            app.videoNameEditFieldLabel.Text = 'videoName';
            app.videoNameEditField = uieditfield(app.UIFigure, 'text');
            app.videoNameEditField.Value = 'video1.mp4';
            app.ReadButton = uibutton(app.UIFigure, 'push');
            app.ReadButton.ButtonPushedFcn = createCallbackFcn(app, @ReadButtonPushed, true);
            app.ReadButton.Text = 'Read';
            app.SaveButton = uibutton(app.UIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.FrameSliderLabel = uilabel(app.UIFigure);
            app.FrameSliderLabel.Text = 'Frame';
            app.FrameSlider = uislider(app.UIFigure);
            app.FrameSlider.ValueChangedFcn = createCallbackFcn(app, @FrameSliderValueChanged, true);
            app.Lamp = uilamp(app.UIFigure);
        end
    end

    methods (Access = public)

        % Construct app
        function app = ReadVideoSaveImage

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end